classdef app1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure               matlab.ui.Figure
        ExportButton           matlab.ui.control.Button
        RunSegmentationButton  matlab.ui.control.Button
        LoadImageButton        matlab.ui.control.Button
        UIAxes_6               matlab.ui.control.UIAxes
        UIAxes_5               matlab.ui.control.UIAxes
        UIAxes_4               matlab.ui.control.UIAxes
        UIAxes_3               matlab.ui.control.UIAxes
        UIAxes_2               matlab.ui.control.UIAxes
        UIAxes                 matlab.ui.control.UIAxes
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: LoadImageButton
        function LoadImageButtonPushed(app, event)
             [file, path] = uigetfile({'*.jpg;*.png;*.bmp;*.tif'}, ...
                             'Select Blood Cell Image');

    if isequal(file,0)
        return;
    end

    img = imread(fullfile(path,file));

    % Convert to grayscale
    if size(img,3) == 3
        gray = rgb2gray(img);
    else
        gray = img;
    end

    % Store image safely
    app.UIFigure.UserData = gray;

    % Display image
    cla(app.UIAxes);                     % clear first
    imshow(gray, 'Parent', app.UIAxes);
    drawnow;

    % Clean axes
    app.UIAxes.XTick = [];
    app.UIAxes.YTick = [];
    app.UIAxes.XLabel.String = '';
    app.UIAxes.YLabel.String = '';
    app.UIAxes.Box = 'off';

        end

        % Button pushed function: RunSegmentationButton
        function RunSegmentationButtonPushed(app, event)
            gray = app.UIFigure.UserData;
    
    if isempty(gray)
        uialert(app.UIFigure,'Please load an image first.','No Image');
        return;
    end
    
    % ---------- Method 1: Otsu ----------
    bw1 = imbinarize(gray, graythresh(gray));
    bw1 = imfill(bw1,'holes');
    
    % ---------- Method 2: Active Contour ----------
    init = bw1;
    bw2 = activecontour(gray, init, 120, 'Chan-Vese');
    
    % ---------- Method 3: Mathematical Morphology ----------
    bw3 = imopen(bw1, strel('disk',2));
    bw3 = imclose(bw3, strel('disk',2));
    bw3 = imfill(bw3,'holes');
    
    % ---------- Method 4: K-Means (Image Toolbox Version) ----------
    L = imsegkmeans(gray, 2);     % segment into 2 clusters
    
    m1 = mean(gray(L==1));
    m2 = mean(gray(L==2));
    
    if m1 < m2
        bw4 = L == 1;
    else
        bw4 = L == 2;
    end

    
    % ---------- Method 5: Watershed ----------
    D = -bwdist(~bw3);
    L = watershed(D);
    bw5 = bw3;
    bw5(L==0) = 0;
    
    % ---------- Display ----------
    imshow(bw1,'Parent',app.UIAxes_2); title(app.UIAxes_2,'Otsu');
    imshow(bw5,'Parent',app.UIAxes_3); title(app.UIAxes_3,'Watershed');
    imshow(bw2,'Parent',app.UIAxes_4); title(app.UIAxes_4,'Active Contour');
    imshow(bw4,'Parent',app.UIAxes_5); title(app.UIAxes_5,'K-Means');
    imshow(bw3,'Parent',app.UIAxes_6); title(app.UIAxes_6,'Morphology');
    
    % Remove axes decorations
    axesList = {app.UIAxes_2, app.UIAxes_3, app.UIAxes_4, ...
                app.UIAxes_5, app.UIAxes_6};
    
    for k = 1:numel(axesList)
        ax = axesList{k};
        ax.XTick = [];
        ax.YTick = [];
        ax.XLabel.String = '';
        ax.YLabel.String = '';
        ax.Box = 'off';
    end
    
    % Store masks for future feature extraction
    app.UIAxes_2.UserData = bw1;
    app.UIAxes_3.UserData = bw5;
    app.UIAxes_4.UserData = bw2;
    app.UIAxes_5.UserData = bw4;
    app.UIAxes_6.UserData = bw3;

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Uploaded Image')
            app.UIAxes_2 = uiaxes(app.UIFigure);
            title(app.UIAxes_2, 'Otsu Method')
            app.UIAxes_3 = uiaxes(app.UIFigure);
            title(app.UIAxes_3, 'Watershed Method')
            app.UIAxes_4 = uiaxes(app.UIFigure);
            title(app.UIAxes_4, 'Active Contour')
            app.UIAxes_5 = uiaxes(app.UIFigure);
            title(app.UIAxes_5, 'K-Means')
            app.UIAxes_6 = uiaxes(app.UIFigure);
            title(app.UIAxes_6, 'Mathematical Morphology')
            app.LoadImageButton = uibutton(app.UIFigure, 'push');
            app.LoadImageButton.ButtonPushedFcn = createCallbackFcn(app, @LoadImageButtonPushed, true);
            app.LoadImageButton.Text = 'Load Image';
            app.RunSegmentationButton = uibutton(app.UIFigure, 'push');
            app.RunSegmentationButton.ButtonPushedFcn = createCallbackFcn(app, @RunSegmentationButtonPushed, true);
            app.RunSegmentationButton.Text = 'Run Segmentation';
            app.ExportButton = uibutton(app.UIFigure, 'push');
            app.ExportButton.Text = 'Export';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end